classdef FiSSH_app_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        FiSSHTab                    matlab.ui.container.Tab
        CABeachRegionDropDownLabel  matlab.ui.control.Label
        CABeachRegionDropDown       matlab.ui.control.DropDown
        SelectoceanconditionsforcomparisonLabel  matlab.ui.control.Label
        ChlaCheckBox                matlab.ui.control.CheckBox
        OceanTempCheckBox           matlab.ui.control.CheckBox
        UpwellingCheckBox           matlab.ui.control.CheckBox
        ChlaValueBox                matlab.ui.control.NumericEditField
        OceanTempValueBox           matlab.ui.control.NumericEditField
        UpwellingValueBox           matlab.ui.control.NumericEditField
        MonthDropDown               matlab.ui.control.DropDown
        FiSSHLabel                  matlab.ui.control.Label
        FindingSuitableSpawningHabitatsLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        Label                       matlab.ui.control.Label
        sdaadLabel                  matlab.ui.control.Label
        sdaadLabel_2                matlab.ui.control.Label
        sdaadLabel_3                matlab.ui.control.Label
        sdaadLabel_4                matlab.ui.control.Label
        sdaadLabel_5                matlab.ui.control.Label
        sdaadLabel_6                matlab.ui.control.Label
        MonthLabel                  matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        sdaadLabel_7                matlab.ui.control.Label
        FINDMOSTSIMILARGRUNIONRUNSButton  matlab.ui.control.StateButton
        sdaadLabel_10               matlab.ui.control.Label
        sdaadLabel_11               matlab.ui.control.Label
        sdaadLabel_12               matlab.ui.control.Label
        sdaadLabel_15               matlab.ui.control.Label
        NumberofDataPointsDropDown  matlab.ui.control.DropDown
        NumberofDataPointsDropDownLabel  matlab.ui.control.Label
        AnimationsTab               matlab.ui.container.Tab
        CREATEMAPVIDEOSButton       matlab.ui.control.Button
        DatasetDropDownLabel        matlab.ui.control.Label
        DatasetDropDown             matlab.ui.control.DropDown
        DatasetDropDownLabel_2      matlab.ui.control.Label
        sdaadLabel_13               matlab.ui.control.Label
        EndMonthDropDown            matlab.ui.control.DropDown
        EndMonthDropDownLabel       matlab.ui.control.Label
        StartMonthDropDown          matlab.ui.control.DropDown
        StartMonthDropDownLabel     matlab.ui.control.Label
        EndYearDropDown             matlab.ui.control.DropDown
        EndYearDropDownLabel        matlab.ui.control.Label
        StartYearDropDown           matlab.ui.control.DropDown
        StartYearDropDownLabel      matlab.ui.control.Label
        RESETSELECTIONSButton       matlab.ui.control.Button
        sdaadLabel_16               matlab.ui.control.Label
        IncludegrunionrunindicatorsDropDown  matlab.ui.control.DropDown
        IncludegrunionrunindicatorsDropDownLabel  matlab.ui.control.Label
        sdaadLabel_18               matlab.ui.control.Label
        sdaadLabel_19               matlab.ui.control.Label
        sdaadLabel_20               matlab.ui.control.Label
        TextArea_2                  matlab.ui.control.TextArea
        sdaadLabel_21               matlab.ui.control.Label
    end


    properties (Access = private)
        Data % Holds the data to display it
    end


    methods (Access = private)

        % Value changed function: FINDMOSTSIMILARGRUNIONRUNSButton
        function FINDMOSTSIMILARGRUNIONRUNSButtonValueChanged(app, event)
        %%% This code executes if the 'Find Most Similar Grunion Runs' Button is clicked %%%
         
            % Define input values
            beach = char(app.CABeachRegionDropDown.Value);
            chl = double(app.ChlaValueBox.Value);
            temp = double(app.OceanTempValueBox.Value);
            upw = double(app.UpwellingValueBox.Value);
            month = str2double(app.MonthDropDown.Value);         
            n = str2double(app.NumberofDataPointsDropDown.Value);
            
            % Load beaches data, create avg vectors between in situ and satellite data for water temperature and chl-a
            load 'mat_files/BEACHES_DATA_small_boxes.mat' %%% mat file packaged with app %%%
            eval(['csv =',beach,';']);
            temp_avg = nanmean([csv.MUR_SST,csv.WTMPmean],2);  % average in situ and satellite values
            chl_avg = nanmean([csv.MODIS_CHL,csv.SCCOOS_CHL],2);  % average in situ and satellite values
                        
            % Find data points that match the input given the month and Walker not NaN
            % If month not specified, will look through all points with Walker not NaN
            if month ~= 0
                possible_inds = find(csv.MM(:) == month & isnan(csv.Walker(:))~=1);
            else
                possible_inds = find(isnan(csv.Walker(:))~=1);
            end
            
            % Grabs data given the boxes that are checked in the app
            matching_pts = []; input = [];
            if app.OceanTempCheckBox.Value
                matching_pts = [matching_pts temp_avg(possible_inds)];
                input = [input temp];
            end  
            if app.ChlaCheckBox.Value
                matching_pts = [matching_pts chl_avg(possible_inds)];
                input = [input chl];
            end
            if app.UpwellingCheckBox.Value
                matching_pts = [matching_pts csv.Upwelling(possible_inds)];
                input = [input upw];
            end
            
            %Check to make sure one of the input factors are checked to run through the algorithm
            if app.OceanTempCheckBox.Value==1 || app.ChlaCheckBox.Value==1 || app.UpwellingCheckBox.Value==1
                % Nearest neighbor algorithm, find n best matches
                nn_ind = knnsearch(matching_pts,input,'K',n,'Distance','seuclidean');
                row_in_csv = possible_inds(nn_ind);
                
                % Write text output for user
                if month==1 || month==2 || month==9 || month==10 || month==11 || month==12 %months out of the grunion run season
                    app.TextArea.Value = ['FiSSH did not find any suitable data points because the month you selected is outside of the grunion spawning season! The California grunion typically spawn between March and August.'];
                else
                    if length(possible_inds) == 1 %one suitable data points
                        app.TextArea.Value = ['FiSSH found ', num2str(length(possible_inds)),...
                            ' suitable data points based on the beach area and month you selected. The Walker Scale value (0-5) representing the size of the grunion run during the most similar conditions to you input was ',...
                            num2str(csv.Walker(row_in_csv(1,1))),'. Displayed below is more information about that run.'];
                    elseif length(possible_inds) ~= 0 %more than one suitable data points
                        app.TextArea.Value = ['FiSSH found ', num2str(length(possible_inds)),...
                            ' suitable data points based on the beach area and month you selected. The Walker Scale value (0-5) representing the size of the grunion run during the most similar conditions to you input was ',...
                            num2str(csv.Walker(row_in_csv(1,1))),'. Displayed below is more information about that run, and other runs FiSSH found to have similar conditions to your input.'];
                    else %no suitable data points
                        app.TextArea.Value = ['FiSSH found ', num2str(length(possible_inds)),...
                            ' suitable data points based on the beach area and month you selected.'];
                    end
                end
                
                % Display results in UITable in app
                new_table = [csv.YYYY(row_in_csv),csv.MM(row_in_csv),csv.Walker(row_in_csv),...
                    temp_avg(row_in_csv),chl_avg(row_in_csv),csv.Upwelling(row_in_csv),...
                    csv.ATMPmin(row_in_csv),csv.PN(row_in_csv)/10000,csv.ENSO_Index(row_in_csv),...
                    csv.PDO_Index(row_in_csv)];
                app.UITable.Data = new_table;
                
            else %Catch if no boxes are checked
                app.TextArea.Value = 'Please select at least one factor.';
            end
        end

        % Button pushed function: CREATEMAPVIDEOSButton
        function CREATEMAPVIDEOSButtonPushed(app, event)
        %%% This code executes if the 'Create Map Videos' Button is clicked %%%            
            
            %Check to make sure all input is selected
            if str2double(app.StartMonthDropDown.Value) == 0 || str2double(app.EndMonthDropDown.Value) == 0 || str2double(app.StartYearDropDown.Value) == 0 || str2double(app.EndYearDropDown.Value) == 0 || str2double(app.DatasetDropDown.Value) == 0
                app.TextArea_2.Value = 'Please complete selections.'; 
            %If all input was selected and the user pushes the button proceed to make map
            else
                app.CREATEMAPVIDEOSButton.Enable = 'off'; %disable button, if someone clicks more than once it will slow analysis and two videos will output
                
                %Create variables for input years and months
                start_month = str2double(app.StartMonthDropDown.Value);
                end_month = str2double(app.EndMonthDropDown.Value);
                start_year = str2double(app.StartYearDropDown.Value);
                end_year = str2double(app.EndYearDropDown.Value);
                dataset = string(app.DatasetDropDown.Value);
                
                %Load satellite imagery data files and grunion run data points
                if dataset == 'chl'
                    load('mat_files/master_monthly_chl_a.mat'); %%% mat file packaged with app %%%
                elseif dataset == 'sst'
                    load('mat_files/master_monthly_sst.mat'); %%% mat file packaged with app %%%
                elseif dataset == 'scv'
                    load('mat_files/master_monthly_oscar.mat'); %%% mat file packaged with app %%%
                end
                load('mat_files/GRUNION_DATA.mat'); %%% mat file packaged with app %%%
                
                lat = double(lat);
                lon = double(lon);
                
                %Create movie file title based on dataset
                if app.IncludegrunionrunindicatorsDropDown.Value == '0'
                    if dataset == 'chl'
                        vid_name = ['chl_',num2str(start_month),'_',num2str(start_year),'_to_',num2str(end_month),'_',num2str(end_year),'.avi'];
                    elseif dataset == 'sst'
                        vid_name = ['sst_',num2str(start_month),'_',num2str(start_year),'_to_',num2str(end_month),'_',num2str(end_year),'.avi'];
                    elseif dataset == 'scv'
                        vid_name = ['osc_',num2str(start_month),'_',num2str(start_year),'_to_',num2str(end_month),'_',num2str(end_year),'.avi'];
                    end
                elseif app.IncludegrunionrunindicatorsDropDown.Value == '1'
                    if dataset == 'chl'
                        vid_name = ['chl_',num2str(start_month),'_',num2str(start_year),'_to_',num2str(end_month),'_',num2str(end_year),'_grunion_runs_indicated.avi'];
                    elseif dataset == 'sst'
                        vid_name = ['sst_',num2str(start_month),'_',num2str(start_year),'_to_',num2str(end_month),'_',num2str(end_year),'_grunion_runs_indicated.avi'];
                    elseif dataset == 'scv'
                        vid_name = ['osc_',num2str(start_month),'_',num2str(start_year),'_to_',num2str(end_month),'_',num2str(end_year),'_grunion_runs_indicated.avi'];
                    end
                end
                
                %Output message in text area
                app.TextArea_2.Value = {'Be patient, FiSSH is creating your video!',['The video will output under the following file name: ',vid_name]};
                
                
                %Set up video writer
                a = VideoWriter(vid_name,'Uncompressed AVI');
                a.FrameRate = 1;
                open(a);
                
                %Set up figure 
                clf
                zz=figure;
                set(zz,'Visible','off'); %Figure window set to off so only the implay video opens
                lat_min=lat(end); lat_max=lat(1); lat_lim = [lat_min,lat_max];
                lon_min=lon(end); lon_max=lon(1); lon_lim = [lon_max,lon_min];
                set(zz,'Position',[0,0,1920,1080]);
                axesm('mercator','frame','on','MapLatLimit',lat_lim,'MapLonLimit',...
                    lon_lim,'MeridianLabel','off','MLabelLocation',1,...
                    'ParallelLabel','off','PLabelLocation',1,'Grid','on','GLineStyle',':',...
                    'mlinelocation',70); %mlinelocation 70 pushes the line off of the grid
                axis tight manual
                [x,y]=meshgrid(lat,lon);
                
                %Find indeces in matrix of the user-selected time frame
                month_vec = {'January' 'February' 'March' 'April' 'May' 'June' 'July' ...
                    'August' 'September' 'October' 'November' 'December'};
                start_ind = find(DATES(:,1)==start_year & DATES(:,2)==start_month);
                end_ind = find(DATES(:,1)==end_year & DATES(:,2)==end_month);
                
                for i = start_ind:end_ind
                   year = DATES(i,1);
                   month = DATES(i,2);
                   month_string = month_vec(month);
                   %MODIS Aqua Chl-a dataset selected, create satellite imagery
                   if dataset == 'chl'
                       layer = squeeze(CHL(i,:,:));
                       surfm(x,y,layer')
                       cb=colorbar('horiz');
                       cb.FontSize = 14;
                       newmap = jet(256);
                       caxis([0 15]);
                       colormap(jet);
                       cb_title = title(cb,'mg/m^3');
                       cb_title.Position = [250 -40 0];
                       cb_title.FontSize=14;
                       title_string = {'MODIS Aqua Chlorophyll-a',[month_string{1},' ',num2str(year)]};
                   %MUR SST dataset selected, create satellite imagery
                   elseif dataset == 'sst'
                       layer = squeeze(SST(i,:,:)-273.15);
                       surfm(x,y,layer)
                       cb=colorbar('horiz');
                       cb.FontSize = 14;
                       newmap = jet(256);
                       caxis([10 22]);
                       colormap(jet);
                       cb_title = title(cb,'C\circ');
                       cb_title.Position = [255 -40 0];
                       cb_title.FontSize=14;
                       title_string = {'MUR Sea Surface Temperature',[month_string{1},' ',num2str(year)]};
                   %OSCAR Ocean Surface Current dataset selected, create satellite imagery
                   elseif dataset == 'scv'
                       u = squeeze(OSCAR_U(i,:,:));
                       v = squeeze(OSCAR_V(i,:,:));
                       quiverm(x,y,u',v') 
                       geoshow('landareas.shp','facecolor','none');
                       title_string = {'OSCAR Ocean Surface Current',[month_string{1},' ',num2str(year)]};
                   end

                   fig_title = title(title_string);
                   fig_title.FontSize = 18;
                   hold on;
                   
                   %Code to plot grunion run indicators on top of satellite imagery if selected
                   if app.IncludegrunionrunindicatorsDropDown.Value == '1'
                       for j = 1:9
                           %Run through data point for each beach
                           if j == 1 %CABRILLO
                               beach_lat = 33.703;beach_lon = -118.289;YEAR = gg_cab.YYYY;MONTH = gg_cab.MM;WALKER = gg_cab.Walker;
                           elseif j == 2 %MALIBU
                               beach_lat = 34.037;beach_lon = -118.609;YEAR = gg_mal.YYYY;MONTH = gg_mal.MM;WALKER = gg_mal.Walker;
                           elseif j == 3 %MONTEREY
                               beach_lat = 36.604;beach_lon = -121.872;YEAR = gg_mon.YYYY;MONTH = gg_mon.MM;WALKER = gg_mon.Walker;
                           elseif j == 4 %OCEANSIDE
                               beach_lat = 33.036;beach_lon = -117.327;YEAR = gg_oce.YYYY;MONTH = gg_oce.MM;WALKER = gg_oce.Walker;
                           elseif j == 5 %ORANGE
                               beach_lat = 33.559;beach_lon = -117.845;YEAR = gg_orn.YYYY;MONTH = gg_orn.MM;WALKER = gg_orn.Walker;
                           elseif j == 6 %SAN DIEGO
                               beach_lat = 32.653;beach_lon = -117.153;YEAR = gg_snd.YYYY;MONTH = gg_snd.MM;WALKER = gg_snd.Walker;
                           elseif j == 7 %SAN FRANCISCO
                               beach_lat = 37.806;beach_lon = -122.465;YEAR = gg_cab.YYYY;MONTH = gg_cab.MM;WALKER = gg_cab.Walker;
                           elseif j == 8 %SANTA BARBARA
                               beach_lat = 34.43;beach_lon = -119.761;YEAR = gg_stb.YYYY;MONTH = gg_stb.MM;WALKER = gg_stb.Walker;
                           elseif j == 9 %VENTURA
                               beach_lat = 34.17;beach_lon = -119.233;YEAR = gg_ven.YYYY;MONTH = gg_ven.MM;WALKER = gg_ven.Walker;
                           end   
                           
                           ind = find(YEAR(:) == year & MONTH(:) == month);
                           
                           %Set the appearance of indicators
                           if WALKER(ind) == 0 || WALKER(ind) == 1
                               plotm(beach_lat,beach_lon,'o','MarkerFaceColor','w',...
                                   'MarkerEdgeColor','k','MarkerSize',10,'LineWidth',3)
                           elseif WALKER(ind) == 2 || WALKER(ind) == 3
                               plotm(beach_lat,beach_lon,'o','MarkerFaceColor','w',...
                                   'MarkerEdgeColor','k','MarkerSize', 15,'LineWidth',3)
                           elseif WALKER(ind) == 4 || WALKER(ind) == 5
                               plotm(beach_lat,beach_lon,'o','MarkerFaceColor','w',...
                                   'MarkerEdgeColor','k','MarkerSize',20,'LineWidth',3)
                           end
                       end
                       
                       %Create dummy plot to make legend stay in entire video for grunion run indicators
                       h = zeros(3, 1);
                       h(1) = plot(NaN,NaN,'ok','MarkerSize',10,'LineWidth',3);
                       h(2) = plot(NaN,NaN,'ok','MarkerSize',15,'LineWidth',3);
                       h(3) = plot(NaN,NaN,'ok','MarkerSize',20,'LineWidth',3);
                       l = legend(h,{'0-1 (Small run)','2-3 (Medium run)','4-5 (Large run)'},'FontSize',14);
                       title(l,'Walker Value')
    
                   end

                   %Write video frame into movie file
                   writeVideo(a,getframe(gcf));
                   hold off; 
                end
                
                close(a);
                implay(vid_name);
            end
        end

        % Value changed function: StartYearDropDown
        function StartYearDropDownValueChanged(app, event)
        %%% This code executes if the 'Start Year' drop down value is selected%%%          
        
            app.StartYearDropDown.Enable = 'off'; %Disable 'Start Year' drop down after a value is selected
            value = str2double(app.StartYearDropDown.Value);

            %change the end year options to be greater than or equal to the start year
            app.EndYearDropDown.Items = app.EndYearDropDown.Items((value-2001):end);
            app.EndYearDropDown.Items = ['--' app.EndYearDropDown.Items];
            app.EndYearDropDown.ItemsData = app.EndYearDropDown.ItemsData((value-2001):end);
            app.EndYearDropDown.ItemsData = [0 app.EndYearDropDown.ItemsData];
            app.EndYearDropDown.Value = 0;

            if value == 2018 %This is a catch for when 2018 is selected, only months 1 through 5 are available
                app.StartMonthDropDown.Items =  {'--','1 - January','2 - February','3 - March','4 - April'};
                app.StartMonthDropDown.ItemsData = {'0','1','2','3','4'};
                app.StartMonthDropDown.Enable = 'on';
                app.EndMonthDropDown.Items = {'--','1 - January','2 - February','3 - March','4 - April','5 - May'};
                app.EndMonthDropDown.ItemsData = {'0','1','2','3','4','5'};
            end
            
            app.StartMonthDropDown.Enable = 'on'; %Enable 'Start Month' drop down after 'Start Year' drop down value is selected
        end

        % Value changed function: StartMonthDropDown
        function StartMonthDropDownValueChanged(app, event)
        %%% This code executes if the 'Start Month' drop down value is selected%%%       
        
            app.StartMonthDropDown.Enable = 'off';%Disable 'Start Month' drop down after a value is selected
            value = str2double(app.StartMonthDropDown.Value);
            app.EndYearDropDown.Enable = 'on'; %Enable 'End Year' drop down after 'Start Month' drop down value is selected
            
        end

        % Value changed function: EndYearDropDown
        function EndYearDropDownValueChanged(app, event)
            %%% This code executes if the 'End Year' drop down value is selected%%%  
            
            app.EndYearDropDown.Enable = 'off'; %Disable 'End Year' drop down after a value is selected
            value = str2double(app.EndYearDropDown.Value);
            
            if value == 2018 %This is a catch for when 2018 is selected, only months 1 through 5 are available
                app.EndMonthDropDown.Items = {'--','1 - January','2 - February','3 - March','4 - April','5 - May'};
                app.EndMonthDropDown.ItemsData = {'0','1','2','3','4','5'};
            end
                
            %This is a catch for if the same start and end year was selected, only the months after the start month can be availabe for the end month
            if value == str2double(app.StartYearDropDown.Value) 
                app.EndMonthDropDown.Items = app.EndMonthDropDown.Items((str2double(app.StartMonthDropDown.Value)+2):end);
                app.EndMonthDropDown.Items = ['--' app.EndMonthDropDown.Items];
                app.EndMonthDropDown.ItemsData = app.EndMonthDropDown.ItemsData((str2double(app.StartMonthDropDown.Value)+2):end);
                app.EndMonthDropDown.ItemsData = ['0' app.EndMonthDropDown.ItemsData];
                app.EndMonthDropDown.Value = '0';
            end
            app.EndMonthDropDown.Enable = 'on'; %Enable 'End Month' drop down after 'End Year' drop down value is selected
            
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            %%% This code executes if the 'Dataset' drop down value is selected%%%  
            
            app.DatasetDropDown.Enable = 'off'; %Disable 'Dataset' drop down after a value is selected
            value = app.DatasetDropDown.Value;
            app.StartYearDropDown.Enable = 'on'; %Enable 'Start Year' drop down after 'Dataset' drop down value is selected
            
        end

        % Button pushed function: RESETSELECTIONSButton
        function RESETSELECTIONSButtonPushed(app, event)
            %%% This code executes if the 'Resect Selections' button is pushed%%%  
            
            %Disable buttons
            app.StartYearDropDown.Enable = 'off';
            app.StartMonthDropDown.Enable = 'off';
            app.EndYearDropDown.Enable = 'off';
            app.EndMonthDropDown.Enable = 'off';
            
            app.StartYearDropDown.Value = '0'; %Reset 'Start Year' drop down starting value
            
            %Reset Start Month drop down options in case they were changed based on the previous input
            app.StartMonthDropDown.Items = {'--','1 - January','2 - February','3 - March','4 - April','5 - May','6 - June',...
                '7 - July','8 - August','9 - September','10 - October','11 - November','12 - December'};
            app.StartMonthDropDown.ItemsData = {'0','1','2','3','4','5','6','7','8','9','10','11','12'};
            app.StartMonthDropDown.Value = '0'; %Reset 'Start Month' drop down starting value
            
            %Reset End Year drop down options in case they were changed based on the previous input
            app.EndYearDropDown.Items = {'--','2003','2004','2005','2006','2007','2008',...
                '2009','2010','2011','2012','2013','2014','2015','2016','2017','2018'};
            app.EndYearDropDown.ItemsData = {'0','2003','2004','2005','2006','2007','2008','2009','2010','2011','2012',...
                '2013','2014','2015','2016','2017','2018'};
            app.EndYearDropDown.Value = '0'; %Reset 'End Year' drop down starting value
            
            %Reset End Month drop down options in case they were changed based on the previous input
            app.EndMonthDropDown.Items = {'--','1 - January','2 - February','3 - March','4 - April','5 - May','6 - June',...
                '7 - July','8 - August','9 - September','10 - October','11 - November','12 - December'};
            app.EndMonthDropDown.ItemsData = {'0','1','2','3','4','5','6','7','8','9','10','11','12'};
            app.EndMonthDropDown.Value = '0'; %Reset 'End Month' drop down starting value
            
            app.DatasetDropDown.Value = '0'; %Reset 'Dataset' drop down starting value
            app.DatasetDropDown.Enable = 'on';
            
            app.IncludegrunionrunindicatorsDropDown.Value = '1'; %Reset 'Include grunion run indicators' drop down
            
            app.TextArea_2.Value = ''; %Clear text area
            
            app.CREATEMAPVIDEOSButton.Enable = 'on'; %Enable 'Create Map Videos Button'
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FiSSHTab = uitab(app.TabGroup);
            app.FiSSHTab.Title = 'FiSSH';
            app.CABeachRegionDropDownLabel = uilabel(app.FiSSHTab);
            app.CABeachRegionDropDownLabel.Text = 'CA Beach Region';
            app.CABeachRegionDropDown = uidropdown(app.FiSSHTab);
            app.CABeachRegionDropDown.Items = {'Cabrillo', 'Malibu', 'Monterey', 'Oceanside', 'Orange', 'San Diego', 'San Francisco', 'Santa Barbara', 'Ventura'};
            app.CABeachRegionDropDown.ItemsData = {'cab', 'mal', 'mon', 'oce', 'orn', 'snd', 'sfo', 'stb', 'ven'};
            app.CABeachRegionDropDown.Value = 'cab';
            app.SelectoceanconditionsforcomparisonLabel = uilabel(app.FiSSHTab);
            app.SelectoceanconditionsforcomparisonLabel.Text = 'Select ocean conditions for comparison:';
            app.ChlaCheckBox = uicheckbox(app.FiSSHTab);
            app.ChlaCheckBox.Text = 'Chlorophyll-a (mg/m^3)';
            app.OceanTempCheckBox = uicheckbox(app.FiSSHTab);
            app.OceanTempCheckBox.Text = 'Ocean Temperature (C)';
            app.UpwellingCheckBox = uicheckbox(app.FiSSHTab);
            app.UpwellingCheckBox.Text = 'Upwelling Index (m^3/s/100m)';
            app.ChlaValueBox = uieditfield(app.FiSSHTab, 'numeric');
            app.ChlaValueBox.Limits = [0 20];
            app.ChlaValueBox.Value = 5;
            app.OceanTempValueBox = uieditfield(app.FiSSHTab, 'numeric');
            app.OceanTempValueBox.Limits = [10 25];
            app.OceanTempValueBox.Value = 15;
            app.UpwellingValueBox = uieditfield(app.FiSSHTab, 'numeric');
            app.UpwellingValueBox.Limits = [-70 255];
            app.UpwellingValueBox.Value = 150;
            app.MonthDropDown = uidropdown(app.FiSSHTab);
            app.MonthDropDown.Items = {'None', 'January - 1', 'February - 2', 'March - 3', 'April - 4', 'May - 5', 'June - 6', 'July - 7', 'August - 8', 'September - 9', 'October - 10', 'November - 11', 'December - 12'};
            app.MonthDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.MonthDropDown.Value = '1';
            app.FiSSHLabel = uilabel(app.FiSSHTab);
            app.FiSSHLabel.Text = 'FiSSH';
            app.FindingSuitableSpawningHabitatsLabel = uilabel(app.FiSSHTab);
            app.FindingSuitableSpawningHabitatsLabel.Text = 'Finding Suitable Spawning Habitats';
            app.UITable = uitable(app.FiSSHTab);
            app.UITable.ColumnName = {'Year'; 'Month'; 'Walker Value'; 'Ocean Temperature (C)'; 'Chlorophyll-a (mg/m^3)'; 'Upwelling Index (m^3/s/100m)'; 'Air Temperature (Celcius)'; 'Pseudo-nitzschia (cells/10,000L)'; 'ENSO'; 'PDO'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.FiSSHTab);
            app.Label.Text = '------------------------------------------------';
            app.sdaadLabel = uilabel(app.FiSSHTab);
            app.sdaadLabel.Text = 'FiSSH uses a compilation of data products ';
            app.sdaadLabel_2 = uilabel(app.FiSSHTab);
            app.sdaadLabel_2.Text = 'including Grunion Greeters citizen science ';
            app.sdaadLabel_3 = uilabel(app.FiSSHTab);
            app.sdaadLabel_3.Text = 'data, in situ ocean measurements, and ';
            app.sdaadLabel_4 = uilabel(app.FiSSHTab);
            app.sdaadLabel_4.Text = 'NASA Earth Observations. Use this app to';
            app.sdaadLabel_5 = uilabel(app.FiSSHTab);
            app.sdaadLabel_5.Text = 'get an idea of what the size of future grunion';
            app.sdaadLabel_6 = uilabel(app.FiSSHTab);
            app.sdaadLabel_6.Text = '';
            app.MonthLabel = uilabel(app.FiSSHTab);
            app.MonthLabel.Text = 'Month';
            app.TextArea = uitextarea(app.FiSSHTab);
            app.sdaadLabel_7 = uilabel(app.FiSSHTab);
            app.sdaadLabel_7.Text = 'given the data we have from past runs. ';
            app.FINDMOSTSIMILARGRUNIONRUNSButton = uibutton(app.FiSSHTab, 'state');
            app.FINDMOSTSIMILARGRUNIONRUNSButton.ValueChangedFcn = createCallbackFcn(app, @FINDMOSTSIMILARGRUNIONRUNSButtonValueChanged, true);
            app.FINDMOSTSIMILARGRUNIONRUNSButton.Text = 'FIND MOST SIMILAR GRUNION RUNS';
            app.sdaadLabel_10 = uilabel(app.FiSSHTab);
            app.sdaadLabel_10.Text = 'Created by the 2018 Summer JPL NASA DEVELOP Southern California Water Resources II Team: ';
            app.sdaadLabel_11 = uilabel(app.FiSSHTab);
            app.sdaadLabel_11.Text = 'Alexandra Jones, Harrison Knapp, Annemarie Peacock, and Lael Wakamatsu';
            app.sdaadLabel_12 = uilabel(app.FiSSHTab);
            app.sdaadLabel_12.Text = 'runs may be along the California coastline ';
            app.sdaadLabel_15 = uilabel(app.FiSSHTab);
            app.sdaadLabel_15.Text = 'Enjoy!';
            app.NumberofDataPointsDropDown = uidropdown(app.FiSSHTab);
            app.NumberofDataPointsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofDataPointsDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofDataPointsDropDown.Value = '1';
            app.NumberofDataPointsDropDownLabel = uilabel(app.FiSSHTab);
            app.NumberofDataPointsDropDownLabel.Text = 'Number of Data Points';
            app.AnimationsTab = uitab(app.TabGroup);
            app.AnimationsTab.Title = 'Animations';
            app.CREATEMAPVIDEOSButton = uibutton(app.AnimationsTab, 'push');
            app.CREATEMAPVIDEOSButton.ButtonPushedFcn = createCallbackFcn(app, @CREATEMAPVIDEOSButtonPushed, true);
            app.CREATEMAPVIDEOSButton.Text = 'CREATE MAP VIDEOS';
            app.DatasetDropDownLabel = uilabel(app.AnimationsTab);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.AnimationsTab);
            app.DatasetDropDown.Items = {'--', 'MUR Sea Surface Temperature', 'MODIS Aqua Chlorophyll-a', 'OSCAR Ocean Surface Current'};
            app.DatasetDropDown.ItemsData = {'0', 'sst', 'chl', 'scv'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = '0';
            app.DatasetDropDownLabel_2 = uilabel(app.AnimationsTab);
            app.DatasetDropDownLabel_2.Text = 'Movie Time Frame';
            app.sdaadLabel_13 = uilabel(app.AnimationsTab);
            app.sdaadLabel_13.Text = 'Select from the parameters below to create satellite imagery videos of the California coastline.';
            app.EndMonthDropDown = uidropdown(app.AnimationsTab);
            app.EndMonthDropDown.Items = {'--', 'January - 1', 'February - 2', 'March - 3', 'April - 4', 'May - 5', 'June - 6', 'July - 7', 'August - 8', 'September - 9', 'October - 10', 'November - 11', 'December - 12'};
            app.EndMonthDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.EndMonthDropDown.Enable = 'off';
            app.EndMonthDropDown.Value = '0';
            app.EndMonthDropDownLabel = uilabel(app.AnimationsTab);
            app.EndMonthDropDownLabel.Text = 'End Month';
            app.StartMonthDropDown = uidropdown(app.AnimationsTab);
            app.StartMonthDropDown.Items = {'--', 'January - 1', 'February - 2', 'March - 3', 'April - 4', 'May - 5', 'June - 6', 'July - 7', 'August - 8', 'September - 9', 'October - 10', 'November - 11', 'December - 12'};
            app.StartMonthDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.StartMonthDropDown.ValueChangedFcn = createCallbackFcn(app, @StartMonthDropDownValueChanged, true);
            app.StartMonthDropDown.Enable = 'off';
            app.StartMonthDropDown.Value = '0';
            app.StartMonthDropDownLabel = uilabel(app.AnimationsTab);
            app.StartMonthDropDownLabel.Text = 'Start Month';
            app.EndYearDropDown = uidropdown(app.AnimationsTab);
            app.EndYearDropDown.Items = {'--', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018'};
            app.EndYearDropDown.ItemsData = {'0', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018'};
            app.EndYearDropDown.ValueChangedFcn = createCallbackFcn(app, @EndYearDropDownValueChanged, true);
            app.EndYearDropDown.Enable = 'off';
            app.EndYearDropDown.Value = '0';
            app.EndYearDropDownLabel = uilabel(app.AnimationsTab);
            app.EndYearDropDownLabel.Text = 'End Year';
            app.StartYearDropDown = uidropdown(app.AnimationsTab);
            app.StartYearDropDown.Items = {'--', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018'};
            app.StartYearDropDown.ItemsData = {'0', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018'};
            app.StartYearDropDown.ValueChangedFcn = createCallbackFcn(app, @StartYearDropDownValueChanged, true);
            app.StartYearDropDown.Enable = 'off';
            app.StartYearDropDown.Value = '0';
            app.StartYearDropDownLabel = uilabel(app.AnimationsTab);
            app.StartYearDropDownLabel.Text = 'Start Year';
            app.RESETSELECTIONSButton = uibutton(app.AnimationsTab, 'push');
            app.RESETSELECTIONSButton.ButtonPushedFcn = createCallbackFcn(app, @RESETSELECTIONSButtonPushed, true);
            app.RESETSELECTIONSButton.Text = 'RESET SELECTIONS';
            app.sdaadLabel_16 = uilabel(app.AnimationsTab);
            app.sdaadLabel_16.Text = 'Click the button below to create your map video. The video will open automatically after FiSSH ';
            app.IncludegrunionrunindicatorsDropDown = uidropdown(app.AnimationsTab);
            app.IncludegrunionrunindicatorsDropDown.Items = {'Yes', 'No'};
            app.IncludegrunionrunindicatorsDropDown.ItemsData = {'1', '0'};
            app.IncludegrunionrunindicatorsDropDown.Value = '1';
            app.IncludegrunionrunindicatorsDropDownLabel = uilabel(app.AnimationsTab);
            app.IncludegrunionrunindicatorsDropDownLabel.Text = 'Include grunion run indicators?';
            app.sdaadLabel_18 = uilabel(app.AnimationsTab);
            app.sdaadLabel_18.Text = 'The indicators will be in the form of dots, where the relative size of the dots reflects the ';
            app.sdaadLabel_19 = uilabel(app.AnimationsTab);
            app.sdaadLabel_19.Text = 'maximum run size during the month for the given beach regions. The absence of a dot';
            app.sdaadLabel_20 = uilabel(app.AnimationsTab);
            app.sdaadLabel_20.Text = 'indicates that there were no documented runs in that region.';
            app.TextArea_2 = uitextarea(app.AnimationsTab);
            app.sdaadLabel_21 = uilabel(app.AnimationsTab);
            app.sdaadLabel_21.Text = 'is done processing.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FiSSH_app_final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end